classdef CanvasHomeworkSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        AvailableAssignmentsListBoxLabel  matlab.ui.control.Label
        Assignments  matlab.ui.control.ListBox
        Select       matlab.ui.control.Button
        Cancel       matlab.ui.control.Button
    end


    properties (Access = private)
        baseApp Autograder
        homeworkId char
        homeworkName char
        names;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, base)
            if nargin == 2
                app.baseApp = base;
                token = app.baseApp.canvasToken;
            end
            API = 'https://gatech.instructure.com/api/v1/';
            COURSE_CODE = 'CS 1371';
            % request assignment details from canvas
            apiOpts = weboptions;
            apiOpts.RequestMethod = 'GET';
            apiOpts.HeaderFields = {'Authorization', ['Bearer ' token]};
            
            % get course ID
            % find course ID; it will be the only course active
            data = webread([API 'courses/'], 'per_page', '100', apiOpts);
            % data will PROBABLY be a cell array
            if ~iscell(data)
            % a stucture. num2cell it and proceed
                data = num2cell(data);
            end
            % loop through all data; if we find an end_at that fits, with the course_code starting with 'CS 1371', that's our guy!
            for d = 1:numel(data)
                ending = datetime(data{d}.end_at,'InputFormat','yyyy-MM-dd''T''HH:mm:ssXXX', 'TimeZone', 'America/New_York');
                starting = datetime(data{d}.start_at,'InputFormat','yyyy-MM-dd''T''HH:mm:ssXXX', 'TimeZone', 'America/New_York');
                ending.TimeZone = '';
                starting.TimeZone = '';
                % if our date is between and name matches, engage. Note that TA course never has a space
                if strncmp(data{d}.course_code, COURSE_CODE, length(COURSE_CODE)) && starting < datetime() && ending > datetime()
                    % This is our course!
                    courseId = data{d}.id;
                    app.baseApp.canvasCourseId = num2str(courseId);
                    break;
                end
            end
            
            data = webread([API 'courses/' num2str(courseId) '/assignments'], 'per_page', '100', 'search_term', 'Homework', apiOpts);
            % if it works, list all assignments
            items = cell(1, numel(data));
            app.names = cell(1, numel(data));
            for d = 1:numel(data)
                if ~contains(data{d}.name, 'Comment EC')
                    items{d} = data{d}.name;
                    app.names{d} = {items{d}, num2str(data{d}.id)};
                end
            end
            app.names = app.names(~cellfun(@isempty, items));
            items = items(~cellfun(@isempty, items));
            app.Assignments.Items = items;
            app.Assignments.ItemsData = 1:numel(items);
        end

        % Button pushed function: Select
        function SelectButtonPushed(app, event)
            % try to get num
            try
                num = str2double(app.homeworkName(app.homeworkName <= '9' & app.homeworkName >= '0'));
            catch
                num = 0;
            end
            % try to get resub
            if contains(lower(app.homeworkName), 'resubmission')
                resub = true;
            else
                resub = false;
            end
            app.baseApp.homeworkNum = num;
            app.baseApp.HomeworkNumber.Value = num;
            app.baseApp.isResubmission = resub;
            app.baseApp.IsResubmission.Value = resub;
            app.baseApp.canvasHomeworkId = app.homeworkId;
            uiresume(app.UIFigure);
        end

        % Value changed function: Assignments
        function AssignmentsValueChanged(app, event)
            value = app.Assignments.Value;
            app.homeworkId = app.names{value}{2};
            app.homeworkName = app.names{value}{1};
        end

        % Button pushed function: Cancel
        function CancelButtonPushed(app, event)
            uiresume(app.UIFigure);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Canvas Assignment Selector';
            app.AvailableAssignmentsListBoxLabel = uilabel(app.UIFigure);
            app.AvailableAssignmentsListBoxLabel.Text = 'Available Assignments';
            app.Assignments = uilistbox(app.UIFigure);
            app.Assignments.Items = {};
            app.Assignments.ValueChangedFcn = createCallbackFcn(app, @AssignmentsValueChanged, true);
            app.Assignments.Value = {};
            app.Select = uibutton(app.UIFigure, 'push');
            app.Select.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.Select.Text = 'Select';
            app.Cancel = uibutton(app.UIFigure, 'push');
            app.Cancel.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.Cancel.Text = 'Cancel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CanvasHomeworkSelector(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end