classdef CheatDetector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Students          matlab.ui.control.ListBox
        CheatDetails      matlab.ui.container.Tree
        Summary           matlab.ui.control.Label
        ShowOnlyCheaters  matlab.ui.control.CheckBox
        Export            matlab.ui.control.Button
    end


    properties (Access = private)
        students;
        problems;
        scores;
        likelyCheaters;
        criticalStudents;
        cheaterStudents;
    end

    properties (Constant)
        THRESHOLD = .90;
    end

    methods (Access = private)
    
        function analyze(app)
            progress = uiprogressdlg(app.UIFigure, 'Title', 'Cheat Detector', 'Message', 'Summarizing Score Data', ...
                'Cancelable', 'off', 'Value', 0, 'Indeterminate', 'off', 'ShowPercentage', 'on');
            for s = numel(app.students):-1:1
                progress.Value = min([progress.Value + 1/numel(app.students), 1]);
                for p = numel(app.problems):-1:1
                    problem = app.problems(p);
                    % Don't list ABCs
                    if ~contains(problem.name, 'ABCs')
                        pScores = cellfun(@(v)(v(p)), app.scores{s});
                        cScores = pScores(pScores >= app.THRESHOLD);
                        cheaters = app.students(pScores >= app.THRESHOLD);
                        app.criticalStudents{s}{p} = cheaters(cScores == Inf);
                        app.cheaterStudents{s}{p} = cheaters;
                    end
                end
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, students, problems, scores)
            app.students = students;
            app.problems = problems;
            app.scores = scores;
            
            % scores is 1:N cell array; each entry is again 1xN cell array of score vectors
            % Basically, for each student, if ANY SCORE is Inf, raise the alarm
            mask = ~contains({app.problems.name}, 'ABCs');
            app.likelyCheaters = cellfun(@getLikely, app.scores);
            
            
            function tf = getLikely(score)
                % score is cell array of vectors
                score = cellfun(@(s)(s(mask)), score, 'uni', false);
                tf = any([score{:}] >= app.THRESHOLD);
            end
            app.Students.Items = cellfun(@(str)([str ')']), ...
                join([{app.students.name}; {app.students.id}]', ' ('), 'uni', false);
            app.Students.ItemsData = 1:numel(app.students);
            
            app.analyze;
        end

        % Value changed function: Students
        function StudentsValueChanged(app, event)
            progress = uiprogressdlg(app.UIFigure);
            progress.Title = 'Report Progress';
            progress.Message = 'Loading Student Cheating Details...';
            progress.Value = 0;
            progress.ShowPercentage = 'on';
            cleaner = {onCleanup(@()(close(progress))), onCleanup(@drawnow)};
            value = app.Students.Value;
            student = app.students(value);
            score = app.scores{value};
            % for each problem create node for it.
            app.CheatDetails.Children.delete;
            synopsisStudents = {};
            isCheat = false;
            for p = 1:numel(app.problems)
                progress.Value = min([progress.Value + 1/numel(app.problems), 1]);
                problem = app.problems(p);
                % Don't list ABCs
                isCritical = false;
                if ~contains(problem.name, 'ABCs')
                    pScores = cellfun(@(v)(v(p)), score);
                    pNode = uitreenode(app.CheatDetails, 'Text', problem.name);
                    % get all scores greater than some threshold
                    cheaters = app.students(pScores >= app.THRESHOLD);
                    if ~isempty(cheaters)
                        isCheat = true;
                    end
                    cScores = pScores(pScores >= app.THRESHOLD);
                    % for each cheater, add name
                    for c = 1:numel(cheaters)
                        cNode = uitreenode(pNode, 'Text', ...
                            [cheaters(c).name ' (' cheaters(c).id ')']);
                        if cScores(c) == Inf
                            cNode.Text = [cheaters(c).name ' (' cheaters(c).id ') - 100.00% match'];
                            cNode.Icon = './resources/critical.png';
                            synopsisStudents{end+1} = cheaters(c);
                            pNode.Icon = cNode.Icon;
                            isCritical = true;
                        else
                            % not inf, list score
                            cNode.Icon = './resources/warning.png';
                            cNode.Text = [cheaters(c).name ' (' cheaters(c).id ') - ' sprintf('%0.2f', 100 * cScores(c)) '% match'];
                        end
                    end
                    if ~isCritical && numel(pNode.Children) ~= 0
                        pNode.Icon = './resources/warning.png';
                    end    
                end
            end
            synopsisStudents = [synopsisStudents{:}];
            if isempty(synopsisStudents)
                names = {};
            else
                names = unique({synopsisStudents.name});
            end
            if ~isCheat
                app.Summary.Text = 'It is unlikely that this student colluded with anyone';
                return;
            elseif numel(names) == 0
                app.Summary.Text = sprintf('It''s exceedingly likely (though not certain) that %s cheated', student.name);
                return;
            elseif numel(names) == 1
                app.Summary.Text = sprintf('It''s almost certain that %s and %s colluded', ...
                    student.name, names{1});
            else
                app.Summary.Text = sprintf('It''s almost certain %s cheated with multiple students', student.name);
            end
            uialert(app.UIFigure, sprintf('Likely cheater(s):\n%s', strjoin(names, newline)), 'Cheat Detected');
        end

        % Value changed function: ShowOnlyCheaters
        function ShowOnlyCheatersValueChanged(app, event)
            value = app.ShowOnlyCheaters.Value;
            if value
                likely = app.students(app.likelyCheaters);
                app.Students.Items = cellfun(@(str)([str ')']), ...
                    join([{likely.name}; {likely.id}]', ' ('), 'uni', false);
                app.Students.ItemsData = find(app.likelyCheaters);
            else
                app.Students.Items = cellfun(@(str)([str ')']), ...
                    join([{app.students.name}; {app.students.id}]', ' ('), 'uni', false);
                app.Students.ItemsData = 1:numel(app.students);
            end
            drawnow;
        end

        % Button pushed function: Export
        function ExportButtonPushed(app, event)
            % Exporting is creating:
            %   For each student
            %       For each problem
            %           Folder, all code that matched; additionally, CSV manifest & Html?
            % MAT file for all


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Cheat Detector';
            app.Students = uilistbox(app.UIFigure);
            app.Students.Items = {};
            app.Students.ValueChangedFcn = createCallbackFcn(app, @StudentsValueChanged, true);
            app.Students.Value = {};
            app.CheatDetails = uitree(app.UIFigure);
            app.Summary = uilabel(app.UIFigure);
            app.Summary.Text = 'Summary';
            app.ShowOnlyCheaters = uicheckbox(app.UIFigure);
            app.ShowOnlyCheaters.ValueChangedFcn = createCallbackFcn(app, @ShowOnlyCheatersValueChanged, true);
            app.ShowOnlyCheaters.Text = 'Only Show Likely Cheaters';
            app.Export = uibutton(app.UIFigure, 'push');
            app.Export.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.Export.Text = 'Export';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CheatDetector(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end