classdef GoogleDriveAuthorizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GoogleAuthorizationUIFigure  matlab.ui.Figure
        Instructions                 matlab.ui.control.Label
        RefreshTokenLabel            matlab.ui.control.Label
        RefreshToken                 matlab.ui.control.EditField
        TokenDescription             matlab.ui.control.Label
        Exit                         matlab.ui.control.Button
    end


    properties (Access = private)
        baseApp Autograder;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, base)
            % Step 1: Get Device Verification Code
            app.baseApp = base;
            try
                app.baseApp.driveToken = authorizeWithGoogle();
                app.RefreshToken.Value = app.baseApp.driveToken;
            catch
                % what do we do?
                uialert(app.GoogleAuthorizationUIFigure, ...
                    'We''re having trouble authenticating you... Try again later (Are you connected to the internet?', ...
                    'Google Drive Authentication');
            end
        end

        % Button pushed function: Exit
        function ExitButtonPushed(app, event)
            uiresume(app.GoogleAuthorizationUIFigure);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GoogleAuthorizationUIFigure = uifigure;
            app.GoogleAuthorizationUIFigure.Name = 'Google Authorization';
            app.Instructions = uilabel(app.GoogleAuthorizationUIFigure);
            app.Instructions.Text = {'To set up your access to Google Drive, '; 'We need you to allow the autograder to access your Drive folder.'};
            app.RefreshTokenLabel = uilabel(app.GoogleAuthorizationUIFigure);
            app.RefreshTokenLabel.Text = 'Token';
            app.RefreshToken = uieditfield(app.GoogleAuthorizationUIFigure, 'text');
            app.TokenDescription = uilabel(app.GoogleAuthorizationUIFigure);
            app.TokenDescription.Text = 'Keep this token safe - you''ll need it if you ever want to Access Google Drive again';
            app.Exit = uibutton(app.GoogleAuthorizationUIFigure, 'push');
            app.Exit.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.Exit.Text = 'Exit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GoogleDriveAuthorizer(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GoogleAuthorizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GoogleAuthorizationUIFigure)
        end
    end
end