classdef ServerAuthorizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ServerUsernameLabel  matlab.ui.control.Label
        ServerUsername       matlab.ui.control.EditField
        ServerPasswordLabel  matlab.ui.control.Label
        ServerPassword       matlab.ui.control.EditField
        Instructions         matlab.ui.control.Label
        ConfirmButton        matlab.ui.control.Button
        CancelButton         matlab.ui.control.Button
    end


    properties (Access = private)
        baseApp Autograder; % Description
        pass = '';
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, base)
            app.baseApp = base;
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            % Check if can connect?
          
            app.baseApp.serverUsername = app.ServerUsername.Value;
            app.baseApp.serverPassword = app.ServerPassword.Value;
            app.baseApp.savePreferences();
            uiresume(app.UIFigure);
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            uiresume(app.UIFigure);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Server Authorizer';
            app.ServerUsernameLabel = uilabel(app.UIFigure);
            app.ServerUsernameLabel.Text = 'Username';
            app.ServerUsername = uieditfield(app.UIFigure, 'text');
            app.ServerPasswordLabel = uilabel(app.UIFigure);
            app.ServerPasswordLabel.Text = 'Password';
            app.ServerPassword = uieditfield(app.UIFigure, 'text');
            app.Instructions = uilabel(app.UIFigure);
            app.Instructions.Text = {'Please enter your username and password for uploading'; 'documents to the CS 1371 Server'};
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ServerAuthorizer(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end