classdef StudentSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        StudentswithSubmissionsListBoxLabel  matlab.ui.control.Label
        AvailableListBox              matlab.ui.control.ListBox
        SelectedStudentsListBoxLabel  matlab.ui.control.Label
        SelectedListBox               matlab.ui.control.ListBox
        AddButton                     matlab.ui.control.Button
        RemoveButton                  matlab.ui.control.Button
        SearchEditFieldLabel          matlab.ui.control.Label
        SearchField                   matlab.ui.control.EditField
        SelectAllButton               matlab.ui.control.Button
        DeselectAllButton             matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        SubmitButton                  matlab.ui.control.Button
    end


    properties (Access = private)
        students
    end

    methods (Access = private)
    
        function getStudents(app, mainapp)
            p = uiprogressdlg(app.UIFigure, 'Title', 'Fetching Students', ...
                            'Message', 'Fetching student information from Canvas', 'Indeterminate', 'on');
            app.students = getCanvasStudents(mainapp.canvasCourseId, mainapp.canvasHomeworkId, ...
                mainapp.canvasToken, p);
            app.AvailableListBox.Items = {app.students.name};
            app.AvailableListBox.ItemsData = app.students;
            close(p);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function onStartup(app, mainapp)
            addpath(genpath(fileparts(fileparts(mfilename('fullpath')))));
            getStudents(app, mainapp);
        end

        % Button pushed function: SelectAllButton
        function SelectAllPressed(app, event)
            app.AvailableListBox.Value = app.AvailableListBox.ItemsData;
        end

        % Button pushed function: DeselectAllButton
        function DeselectAllPressed(app, event)
            app.AvailableListBox.Value = {};
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Student Selector';
            app.StudentswithSubmissionsListBoxLabel = uilabel(app.UIFigure);
            app.StudentswithSubmissionsListBoxLabel.Text = 'Students with Submissions';
            app.AvailableListBox = uilistbox(app.UIFigure);
            app.AvailableListBox.Items = {};
            app.AvailableListBox.Multiselect = 'on';
            app.AvailableListBox.Value = {};
            app.SelectedStudentsListBoxLabel = uilabel(app.UIFigure);
            app.SelectedStudentsListBoxLabel.Text = 'Selected Students';
            app.SelectedListBox = uilistbox(app.UIFigure);
            app.SelectedListBox.Items = {};
            app.SelectedListBox.Multiselect = 'on';
            app.SelectedListBox.Value = {};
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.Text = '>>';
            app.RemoveButton = uibutton(app.UIFigure, 'push');
            app.RemoveButton.Text = '<<';
            app.SearchEditFieldLabel = uilabel(app.UIFigure);
            app.SearchEditFieldLabel.Text = 'Search';
            app.SearchField = uieditfield(app.UIFigure, 'text');
            app.SelectAllButton = uibutton(app.UIFigure, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllPressed, true);
            app.SelectAllButton.Text = 'Select All';
            app.DeselectAllButton = uibutton(app.UIFigure, 'push');
            app.DeselectAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllPressed, true);
            app.DeselectAllButton.Text = 'Deselect All';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.Text = 'Cancel';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.Text = 'Submit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = StudentSelector(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)onStartup(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end